classdef lisp_detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TextArea                      matlab.ui.control.TextArea
        TextAreaLabel                 matlab.ui.control.Label
        CreateNewConfigFileButton     matlab.ui.control.Button
        LoadexistingConfigFileButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        %% initialize audio devices
        % input audio device
        In = audioDeviceReader;
        
        % set frame window in samples
        frameLength = 0.5; % seconds
        %sampleRate = In.SampleRate;


        %Values from calibration file
        mode
        normalFreqs
        lispFreqs
        restFreqs
        params
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % input audio device
            app.In.Device = "default";
            %set sample rate
            %app.In.SamplesPerFrame = app.sampleRate * app.frameLength;
            
            fileName = fopen('calibration.config');
            formatSpec = '%q';
            % save file to cell array
            calibration = textscan(fileName,formatSpec);

            % get mode from cell array 
            app.mode = calibration{1}(1, 1);
            app.mode = string(app.mode);
            
            % get normalFreqs from cell array
            app.normalFreqs = calibration{1}(2, 1);
            app.normalFreqs = cell2mat(app.normalFreqs);
            app.normalFreqs = strsplit(app.normalFreqs,',');
            app.normalFreqs = str2double(app.normalFreqs);   

            % get lispFreqs from cell array
            app.lispFreqs = calibration{1}(3, 1);
            app.lispFreqs = cell2mat(app.lispFreqs);
            app.lispFreqs = strsplit(app.lispFreqs,',');
            app.lispFreqs = str2double(app.lispFreqs);

            % get restFreqs from cell array
            app.restFreqs = calibration{1}(4, 1);
            app.restFreqs = cell2mat(app.restFreqs);
            app.restFreqs = strsplit(app.restFreqs,',');
            app.restFreqs = str2double(app.restFreqs);    

            app.params = [app.normalFreqs', app.lispFreqs', app.restFreqs'];    

            disp('huhu');
            app.TextArea.Value = string(app.restFreqs);
        end

        % Button pushed function: CreateNewConfigFileButton
        function CreateNewConfigFileButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadexistingConfigFileButton = uibutton(app.UIFigure, 'push');
            app.LoadexistingConfigFileButton.Text = 'Load existing Config File';
            app.CreateNewConfigFileButton = uibutton(app.UIFigure, 'push');
            app.CreateNewConfigFileButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewConfigFileButtonPushed, true);
            app.CreateNewConfigFileButton.Text = 'Create New Config File';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lisp_detection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end